classdef app_entrenado_y_test < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                      matlab.ui.container.GridLayout
        puertoIP                        matlab.ui.control.EditField
        EditFieldLabel_3                matlab.ui.control.Label
        IncorrectaButton                matlab.ui.control.Button
        CorrectaButton                  matlab.ui.control.Button
        Recuerdequelaclase1correspondealamanoderechaLabel  matlab.ui.control.Label
        electrodos                      matlab.ui.control.EditField
        EditFieldLabel_2                matlab.ui.control.Label
        RefreshmodelosButton            matlab.ui.control.Button
        LedEstado                       matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PuertoCOM                       matlab.ui.control.EditField
        PuertoCOMEditFieldLabel         matlab.ui.control.Label
        archivoEntrenamiento            matlab.ui.control.DropDown
        Archivodeentrenamientomodel_cxcx_usuarioDropDownLabel  matlab.ui.control.Label
        Bandadefrecuencia3DropDown      matlab.ui.control.DropDown
        Bandadefrecuencia3Label         matlab.ui.control.Label
        Bandadefrecuencia2DropDown      matlab.ui.control.DropDown
        Bandadefrecuencia2Label         matlab.ui.control.Label
        Bandadefrecuencia1DropDown      matlab.ui.control.DropDown
        Bandadefrecuencia1Label         matlab.ui.control.Label
        tiemporealButton                matlab.ui.control.Button
        resultados                      matlab.ui.control.TextArea
        ResultadosdelentrenamientoLabel  matlab.ui.control.Label
        NombredelUsuarioEditField       matlab.ui.control.EditField
        NombredelUsuarioEditFieldLabel  matlab.ui.control.Label
        ENTRENARButton                  matlab.ui.control.Button
        archivo2                        matlab.ui.control.DropDown
        SeleccioneelsegundoarchivoDropDownLabel  matlab.ui.control.Label
        archivo1                        matlab.ui.control.DropDown
        SeleccioneelprimerarchivoLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        BienvenidatuBCILabel            matlab.ui.control.Label
    end

    properties (Access = private)
        PendingResponse char = ' ';
    end
    
    
    methods (Access = private)
        
        function ch= appResponseProvider(comp, ~)
             % Mostrar el prompt en la UI
            comp.PendingResponse = ''; 
            drawnow;

            % Esperar a que el usuario presione Correcto o Incorrecto
            while isempty(comp.PendingResponse)
                pause(0.05);
                drawnow limitrate;
            end

            % Recuperar respuesta y limpiar
            ch = char(string(comp.PendingResponse));
            comp.PendingResponse = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % 1) Define la carpeta de búsqueda
            folder_path = pwd; % direccion de la carpeta actual
            comp.UserData.folder_path = folder_path; % para poder acceder a la info
        
            archivos = dir(fullfile(folder_path,'*.mat')); % busca los archivos .mat
            modelos= dir(fullfile(folder_path, 'model*.mat'));
            names = {archivos.name}; % obtiene el nombre de los archivos
            nombres_modelos= {modelos.name};
        
            % asignar los nombres de los archivos a los menus
            comp.archivo1.Items = names;
            comp.archivo2.Items = names;

            comp.archivoEntrenamiento.Items= nombres_modelos;

            comp.LedEstado.Color = [0.5 0.5 0.5];


        end

        % Button pushed function: ENTRENARButton
        function ENTRENARButtonPushed(comp, event)

            fig= ancestor(comp,'matlab.ui.Figure')
            folder_path= comp.UserData.folder_path;

            s1_file= comp.archivo1.Value;
            s2_file= comp.archivo2.Value;   
            s1_path = fullfile(folder_path, s1_file);
            s2_path = fullfile(folder_path, s2_file);
            info_file= fullfile(folder_path, 'info_electrodos_frecuencias.mat');

            nombreUsuario= comp.NombredelUsuarioEditField.Value;
            nombreUsuario = regexprep(nombreUsuario, '\W', '_');


            try     
                d = uiprogressdlg(fig,'Title','Entrenando','Message','Por favor espera...','Indeterminate','on');
                log_path= entrenar_con_dos_sesiones_funcion(s1_path, s2_path, nombreUsuario);
                close(d);
                
                if exist(log_path,'file')
                    txt = fileread(log_path);
                    comp.resultados.Value = splitlines(string(txt));  % una línea por renglón
                else
                    comp.resultados.Value = "No se encontró el archivo de log.";
                end

                uialert(fig,'Entrenamiento completado.','Listo', 'Icon', 'success');

            catch ME
                if exist('d','var') && isvalid(d), close(d); end
                st = ME.stack(1);
                msg = sprintf('Error: %s\nFile: %s\nLine: %d', ME.message, st.file, st.line);
                uialert(fig, msg, 'Error durante el entrenamiento', 'Icon', 'error');
            end 
        end

        % Button pushed function: tiemporealButton
        function tiemporealButtonPushed(comp, event)

            nombreUsuario= comp.NombredelUsuarioEditField.Value;
            nombreUsuario = regexprep(nombreUsuario, '\W', '_');

            selec_f_1= str2num(comp.Bandadefrecuencia1DropDown.Value);
            selec_f_2= str2num(comp.Bandadefrecuencia2DropDown.Value);
            selec_f_3= str2num(comp.Bandadefrecuencia3DropDown.Value);
            freq= [selec_f_1; selec_f_2; selec_f_3];

            fig = ancestor(comp,'matlab.ui.Figure'); 
            folder_path= comp.UserData.folder_path; 

            port= strtrim(comp.PuertoCOM.Value); 
            if isempty(port) 
                uialert(fig,'Por favor ingresa el puerto COM.','Falta puerto','Icon','warning'); 
                return; 
            end


            IP= strtrim(comp.puertoIP.Value); 
            if isempty(IP) 
                uialert(fig,'Por favor ingresa el puerto IP.','Falta puerto IP','Icon','warning'); 
                return; 
            end


            electrodos= str2num(comp.electrodos.Value);


            model= comp.archivoEntrenamiento.Value;
            model_path = fullfile(folder_path, model);


            contador= 20;
            

            try     
                comp.LedEstado.Color= [0, 1, 0];
                 rp = @(prompt) appResponseProvider(comp, prompt);

                [results, raw_data, raw_labels] = real_time_brainflow( ...
                IP, port, model_path, freq, electrodos, contador, rp, nombreUsuario);
                uialert(fig,'Prueba en tiempo real finalizada.','Listo','Icon','success');
                comp.LedEstado.Color = [0.5 0.5 0.5];  


            catch ME
                comp.LedEstado.Color= [1 0 0];
                msg = sprintf('Error: %s\nArchivo: %s\nLínea: %d', ...
                    ME.message, ME.stack(1).file, ME.stack(1).line);
                fig = ancestor(comp,'matlab.ui.Figure');
                uialert(fig, msg, 'Error en tiempo real', 'Icon', 'error');
                pause(2);
                comp.LedEstado.Color = [0.5 0.5 0.5];

            end
        end

        % Button pushed function: RefreshmodelosButton
        function RefreshmodelosButtonPushed(comp, event)
            % Carpeta base guardada en postSetupFcn
            if ~isfield(comp.UserData,'folder_path') || ~isfolder(comp.UserData.folder_path)
                comp.UserData.folder_path = pwd;  % respaldo
            end
            folder_path = comp.UserData.folder_path;
            % Buscar archivos que empiecen con "model"
            modelos = dir(fullfile(folder_path,'model*.mat'));
            nombres = {modelos.name};
            % Actualizar items
            comp.archivoEntrenamiento.Items = nombres;
 
        end

        % Button pushed function: CorrectaButton
        function CorrectaButtonPushed(comp, event)
            comp.PendingResponse= 'c';
        end

        % Button pushed function: IncorrectaButton
        function IncorrectaButtonPushed(comp, event)
            comp.PendingResponse= 'i';
 
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 240];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.BienvenidatuBCILabel = uilabel(comp.GridLayout);
            comp.BienvenidatuBCILabel.Text = 'Bienvenid@ a tu BCI ...';
            comp.Image = uiimage(comp.GridLayout);
            comp.Image.ImageSource = 'logo_UDLAP.png';
            comp.SeleccioneelprimerarchivoLabel = uilabel(comp.GridLayout);
            comp.SeleccioneelprimerarchivoLabel.Text = 'Seleccione el primer archivo';
            comp.archivo1 = uidropdown(comp.GridLayout);
            comp.SeleccioneelsegundoarchivoDropDownLabel = uilabel(comp.GridLayout);
            comp.SeleccioneelsegundoarchivoDropDownLabel.Text = 'Seleccione el segundo archivo';
            comp.archivo2 = uidropdown(comp.GridLayout);
            comp.ENTRENARButton = uibutton(comp.GridLayout, 'push');
            comp.ENTRENARButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ENTRENARButtonPushed, true);
            comp.ENTRENARButton.Text = {'ENTRENAR'; ''};
            comp.NombredelUsuarioEditFieldLabel = uilabel(comp.GridLayout);
            comp.NombredelUsuarioEditFieldLabel.Text = 'Nombre del Usuario:';
            comp.NombredelUsuarioEditField = uieditfield(comp.GridLayout, 'text');
            comp.ResultadosdelentrenamientoLabel = uilabel(comp.GridLayout);
            comp.ResultadosdelentrenamientoLabel.Text = 'Resultados del entrenamiento:';
            comp.resultados = uitextarea(comp.GridLayout);
            comp.tiemporealButton = uibutton(comp.GridLayout, 'push');
            comp.tiemporealButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @tiemporealButtonPushed, true);
            comp.tiemporealButton.Text = 'Entrenar en tiempo real';
            comp.Bandadefrecuencia1Label = uilabel(comp.GridLayout);
            comp.Bandadefrecuencia1Label.Text = 'Banda de frecuencia 1:';
            comp.Bandadefrecuencia1DropDown = uidropdown(comp.GridLayout);
            comp.Bandadefrecuencia1DropDown.Items = {'5, 10', '8, 13', '13, 18'};
            comp.Bandadefrecuencia1DropDown.Value = '5, 10';
            comp.Bandadefrecuencia2Label = uilabel(comp.GridLayout);
            comp.Bandadefrecuencia2Label.Text = 'Banda de frecuencia 2:';
            comp.Bandadefrecuencia2DropDown = uidropdown(comp.GridLayout);
            comp.Bandadefrecuencia2DropDown.Items = {'5, 10', '8, 13', '13, 18'};
            comp.Bandadefrecuencia2DropDown.Value = '5, 10';
            comp.Bandadefrecuencia3Label = uilabel(comp.GridLayout);
            comp.Bandadefrecuencia3Label.Text = 'Banda de frecuencia 3:';
            comp.Bandadefrecuencia3DropDown = uidropdown(comp.GridLayout);
            comp.Bandadefrecuencia3DropDown.Items = {'5, 10', '8, 13', '13, 18'};
            comp.Bandadefrecuencia3DropDown.Value = '5, 10';
            comp.Archivodeentrenamientomodel_cxcx_usuarioDropDownLabel = uilabel(comp.GridLayout);
            comp.Archivodeentrenamientomodel_cxcx_usuarioDropDownLabel.Text = 'Archivo de entrenamiento model_cxcx_usuario:';
            comp.archivoEntrenamiento = uidropdown(comp.GridLayout);
            comp.PuertoCOMEditFieldLabel = uilabel(comp.GridLayout);
            comp.PuertoCOMEditFieldLabel.Text = 'Puerto COM:';
            comp.PuertoCOM = uieditfield(comp.GridLayout, 'text');
            comp.Label = uilabel(comp.GridLayout);
            comp.Label.Text = 'Instrucciones: Colocar en el lado izquierdo de la pantalla el nombre del usuario, así como sus archivos de datos obtenidos de la app de entrenamiento, posteriormente, apretar el botón ENTRENAR y esperar a que este se haya completado.';
            comp.Label_2 = uilabel(comp.GridLayout);
            comp.Label_2.Text = 'De acuerdo con los resultados en el cuadro de texto en la parte inferior, seleccionar el archivo de entrenamiento, así como su banda de frecuencia correspondiente. ';
            comp.LedEstado = uilamp(comp.GridLayout);
            comp.RefreshmodelosButton = uibutton(comp.GridLayout, 'push');
            comp.RefreshmodelosButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RefreshmodelosButtonPushed, true);
            comp.RefreshmodelosButton.Text = 'Refresh modelos';
            comp.EditFieldLabel_2 = uilabel(comp.GridLayout);
            comp.EditFieldLabel_2.Text = 'Electrodos escogidos (escribir los 3 números correspondientes)';
            comp.electrodos = uieditfield(comp.GridLayout, 'text');
            comp.Recuerdequelaclase1correspondealamanoderechaLabel = uilabel(comp.GridLayout);
            comp.Recuerdequelaclase1correspondealamanoderechaLabel.Text = 'Recuerde que la clase 1 corresponde a la mano derecha, la clase 2 a la mano izquierda y la clase 3 a una actividad cognitiva alterna';
            comp.CorrectaButton = uibutton(comp.GridLayout, 'push');
            comp.CorrectaButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CorrectaButtonPushed, true);
            comp.CorrectaButton.Text = 'predicción correcta';
            comp.IncorrectaButton = uibutton(comp.GridLayout, 'push');
            comp.IncorrectaButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @IncorrectaButtonPushed, true);
            comp.IncorrectaButton.Text = 'predicción incorrecta';
            comp.EditFieldLabel_3 = uilabel(comp.GridLayout);
            comp.EditFieldLabel_3.Text = 'Puerto IP:';
            comp.puertoIP = uieditfield(comp.GridLayout, 'text');
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end