classdef app_toma_de_registros_iniciales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        PuertoEditField       matlab.ui.control.EditField
        PuertoEditFieldLabel  matlab.ui.control.Label
        file_name             matlab.ui.control.EditField
        IntroduzcaelnombredesuarchivoLabel  matlab.ui.control.Label
        imgStim               matlab.ui.control.Image
        InstructionsLabel     matlab.ui.control.Label
        LogoImage             matlab.ui.control.Image
        AppNameLabel          matlab.ui.control.Label
        STARTButton           matlab.ui.control.Button
        TitleLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        %wasAborted logical = false % Description
    end
    
    methods (Access = private)
        
        function update_image(app,fname)
            app.imgStim.ImageSource= fname;
            drawnow;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable = 'off'; % para que ya no se vuelva a activar

            nombre= strtrim(app.file_name.Value); % guarda el texto que se haya puesto en el recuadro
            
            path_folder= fileparts(mfilename('fullpath')); % path de la carpeta donde todo se guarda

            showUI = @(fname) set(app.imgStim,'ImageSource',fname);

            puertoStr= strtrim(app.PuertoEditField.Value);

        
            try
                [signal, s, Fs] = entrena_bci(puertoStr, showUI, path_folder);

                % guardar el archivo con el nombre que se dio
                timestamp= datestr(now, 'yyyy-mm-dd_HH-MM-SS');
                archivo = fullfile(path_folder, sprintf('%s_%s.mat', nombre, timestamp));
                save(archivo, 'signal','s','Fs');
                message1= ["Trial finished"];
                uialert(app.UIFigure, message1, "Success", "Icon", "success");
                
            catch ME
                % muestra el mensaje de error que haya dado matlab
                uialert(app.UIFigure, ME.message, "Error"); 
            end

            app.STARTButton.Enable= "on"; % volver a poner disponible el botón
            app.imgStim.ImageSource = fullfile(path_folder,'blank.jpg'); % "stand by"


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TitleLabel = uilabel(app.GridLayout);
            app.TitleLabel.Text = 'Bienvenid@ a tu BCI';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.AppNameLabel = uilabel(app.GridLayout);
            app.AppNameLabel.Text = 'Esta es la app de entrenamiento...';
            app.LogoImage = uiimage(app.GridLayout);
            app.LogoImage.ImageSource = fullfile(pathToMLAPP, 'logo_UDLAP.png');
            app.InstructionsLabel = uilabel(app.GridLayout);
            app.InstructionsLabel.Text = 'Instrucciones: Aparecerán flechas dentro del cuadrado que se muestra en la parte inferior. Cuando una flecha apunte hacia la derecha, imagine que mueve la mano derecha; cuando apunte hacia la izquierda, imagine que mueve la mano izquierda. Cuando aparezca la pantalla en blanco, realice una tarea cognitiva alternativa como recordar una canción. Repita esta acción elegida de forma constante durante todas las pruebas.';
            app.imgStim = uiimage(app.GridLayout);
            app.IntroduzcaelnombredesuarchivoLabel = uilabel(app.GridLayout);
            app.IntroduzcaelnombredesuarchivoLabel.Text = 'Introduzca el nombre de su archivo ';
            app.file_name = uieditfield(app.GridLayout, 'text');
            app.PuertoEditFieldLabel = uilabel(app.GridLayout);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.PuertoEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_toma_de_registros_iniciales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end